classdef etchAsketchapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        algoSliderText              matlab.ui.control.Label
        algoSlider                  matlab.ui.control.Slider
        AlgorithmSelectionDropDown  matlab.ui.control.DropDown
        AlgorithmSelectionDropDownLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        TextLocationY_button        matlab.ui.control.EditField
        Run_button                  matlab.ui.control.Button
        EstimatedTime_text          matlab.ui.control.EditField
        AddTextToImageLabel_3       matlab.ui.control.Label
        Reset_button                matlab.ui.control.Button
        TextLocationX_button        matlab.ui.control.EditField
        AddTextToImageLabel_4       matlab.ui.control.Label
        Preview_button              matlab.ui.control.Button
        EnterText                   matlab.ui.control.EditField
        AddTextToImageLabel         matlab.ui.control.Label
        ResolutionSlider            matlab.ui.control.Slider
        BrightnessSlider            matlab.ui.control.Slider
        ContrastSlider              matlab.ui.control.Slider
        OpenImage_button            matlab.ui.control.Button
        FileName_text               matlab.ui.control.EditField
        AddTextToImageLabel_2       matlab.ui.control.Label
        ResolutionSliderLabel       matlab.ui.control.Label
        BrightnessSliderLabel       matlab.ui.control.Label
        ContrastSliderLabel         matlab.ui.control.Label
        ROBOTICETCHASKETCHLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Knob_2                      matlab.ui.control.Knob
        Knob                        matlab.ui.control.Knob
        figure                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Current image
        inputText_position = [100, 300] % Description
        backupimg
        resolutionVal
        contrastVal
        brightnessVal
        algo = "Contours"
        algoSliderVal = 150
    end
    
    methods (Access = private)
        %%% Our Functions %%%
        function updateImage(app)
            app.Image.ImageSource = cat(3, app.img, app.img, app.img);
        end

        function run(app)
            % This is the main code that runs and controls functions.  With this code
            % The arduino stepper commands are controlled to move in x y positions
            % Stored in position matrix, mat: columns of x and y positions
                        
            global arduino_object; % Create an Arduino object
            
            arduino_object = arduino('com6', 'Uno');  %this is an internal program in matlab that creates a follower program in the Arduino that is controlled by the leader matlab program.   The first time this runs it will take a little longer as it will downlad the follower program into the arduino.
            
            %Set and configure pins for arduino
            global stepX dirX stepY dirY enPin;
            
            stepX = 'D2';
            dirX  = 'D5';
            stepY = 'D3';
            dirY  = 'D6';
            enPin = 'D8';
            
            configurePin(arduino_object,'D2','DigitalOutput')
            configurePin(arduino_object,'D5','DigitalOutput')
            configurePin(arduino_object,'D3','DigitalOutput')
            configurePin(arduino_object,'D6','DigitalOutput')
            configurePin(arduino_object,'D8','DigitalOutput')
            
            % Set initial position, lower left hand corner
            % Full range is approximately rangex = 875 rangey = 610
            global currentx currenty;
            currentx = 0;
            currenty = 0;
            
            % Establishes where backlash starts: 0 is negative, 1 is positive
            % Note initial value only influences minor position of x,y start axis
            global currentdirx currentdiry;
            currentdirx = 1;
            currentdiry = 1;
            
            % Set initial backlash; number of steps after changing direciton in order for
            % Visible movement to occur. This is a positive value
            global backlashx backlashy;
            backlashy = 8;
            backlashx = 10;
            
            global time;
            time = 0; % This value of time pauses between movements. note because of arduino and matlab communication this can only increase the speed to a certain degree. 0 is full speed.
            
            
            % Pull up list of x,y points to travel to, mat
            % A seperate function should be put here.
        
            % Preview and run
            app.preview(true);
            
            % Return home
            moveitto(0, 0);
            
            clear all; % Clears all variables, including most importantly "a" which shuts down the stepper motors
        end

        function preview(app, run)
            app.algoSliderValueChanged();
            app.Preview_button.BackgroundColor = "Green";
            if run
                app.Run_button.BackgroundColor = "Green";
            end

            app.Image.Visible = false;
            app.figure.Visible = true;
            app.figure.Parent = app.Panel;
            app.figure.Position = [40, 20, 525, 320];
            l = animatedline('Parent', app.figure);   
            app.figure.XLim = [0 800];
            app.figure.YLim = [0 500];

            numRows = 500;
            numCols = 800;

            % Downsample image
            scale = 0.25;
            app.img = im2gray(app.img);
            app.img = imresize(app.img, scale);

            if or(app.algo == "Canny Edges", app.algo == "Contours")
                if (app.algo == "Canny Edges")
                    threshold = [.1 app.algoSliderVal]; % [low, high]
                    edges = imcomplement(edge(app.img, 'Canny', threshold));
                    [row, col] = find(~edges);
                elseif app.algo == "Contours"
                    [row, col] = find(app.img < app.algoSliderVal);
                end

                mat = [col row];
                mat = mat.*(1 / scale);

                % Set start of drawing to bottom left of Etch-A-Sketch
                curX = 0;
                curY = numRows;
                
                % Calculate points to draw
                pointsLeft = size(mat, 1);
                while pointsLeft > 0
                    pointsLeft = pointsLeft - 1;
                    disp(pointsLeft);
                    
                    % Find index of closest (x, y) pair in mat
                    index = dsearchn(mat, [curX curY]);
                
                    % Set current point to index
                    curX = mat(index, 1);
                    curY = mat(index, 2);
                   
                    % Remove index to be drawn
                    mat(index, :) = [];
                
                    % Draw on figure
                    moveX = int32(curX);
                    moveY = int32(numRows - curY);
                    addpoints(l, double(moveX), double(moveY))
                    drawnow limitrate
                    if run
                        moveitto(moveX, moveY)
                    end                
                end
            elseif app.algo == "Column Modulation"                
                % Downsample image
                colwidth = app.algoSliderVal;
                disp(colwidth)
                smallcols = int32(numCols / colwidth);
                rowheight = 3;
                smallrows = int32(numRows / rowheight);
                
                newimg = imresize(app.img, [smallrows, smallcols]);

                disp(smallcols)
                disp(smallrows)

                for col = 1 : smallcols
                    if mod(col, 2) == 0
                        even = true;
                    else
                        even = false;
                    end
                    
                    disp(even)

                    for row = 1 : smallrows
                        if even
                            val = double(newimg(smallrows - row + 1, col));
                            moveY = int32(rowheight * row);  
                            
                        else
                            val = double(newimg(row, col));
                            moveY = int32(rowheight * (smallrows - row));  

                        end

                        % disp(val)
                        width = interp1([255, 0], [0, colwidth], val);
                    
                        % Draw on figure
                        moveX = int32((colwidth * col) + width);

                        addpoints(l, double(moveX), double(moveY))
                        
                        drawnow limitrate
                        if run
                            moveitto(moveX, moveY)
                        end
                    end
                end
            else
                disp("Error in algorithm selection")
            end
            
            % Reset button colors
            app.Preview_button.BackgroundColor = "#F4F4F4";
            app.Run_button.BackgroundColor = "#F4F4F4";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImage_button
        function OpenImage_buttonButtonPushed(app, event)
            imgName = 'hi'; %uigetfile('*.jpg');
            %app.img = im2gray(imread(imgName));            
            app.img = im2gray(imread('basepic.jpg'));
            app.img = imresize(app.img, [500 800]);
            app.updateImage();
            app.FileName_text.Value = imgName;
            app.backupimg = app.img;

        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            if value > 0
                low_out = interp1([-5, 5], [0, 1], value - 5);
                high_out = 1 - low_out;
                app.img = imadjust(app.img, [low_out high_out], []);

            elseif value < 0
                low_out = 0;
                high_out = interp1([-5, 5], [0, 1], value);
                app.img = imadjust(app.img, [low_out high_out], []);
            end
            app.updateImage();
            app.contrastVal = value;
        end

        % Button pushed function: Preview_button
        function Preview_buttonButtonPushed(app, event)
            app.preview(false);
        end

        % Value changed function: EnterText
        function EnterTextValueChanged(app, event)
            inputText = app.EnterText.Value;
            disp(inputText)
            app.img = insertText(app.img, app.inputText_position, inputText, FontSize = 64, TextColor='black', BoxOpacity = 0);
            app.Image.ImageSource = app.img;
            %app.updateImage()
        end

        % Value changed function: TextLocationX_button
        function TextLocationX_buttonValueChanged(app, event)
            app.inputText_position(1) = str2double(app.TextLocationX_button.Value);
            app.EnterTextValueChanged();
        end

        % Value changed function: TextLocationY_button
        function TextLocationY_buttonValueChanged(app, event)
            app.inputText_position(2) = str2double(app.TextLocationY_button.Value);
            app.EnterTextValueChanged();            
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            scale = interp1([-5, 5], [0.25, 1], value);
            app.img = imresize(app.img, scale);
            app.updateImage();
            app.resolutionVal = value;
        end

        % Button pushed function: Reset_button
        function Reset_buttonButtonPushed(app, event)
            if ~isempty(allchild(app.figure))
                app.figure.Visible = false;
                cla(app.figure)
            end
            app.img = app.backupimg;
            app.Image.Visible = true;
            app.contrastVal = 0;
            app.ContrastSlider.Value = 0;
            app.brightnessVal = 0;
            app.BrightnessSlider.Value = 0;
            app.resolutionVal = 0;
            app.ResolutionSlider.Value = 0;
            app.updateImage();
            app.Image.Visible = true;
            app.algo = "Contours";
            app.AlgorithmSelectionDropDownValueChanged();
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.img = app.img + (value * 10);
            app.updateImage();
            app.brightnessVal = value;
        end

        % Value changed function: AlgorithmSelectionDropDown
        function AlgorithmSelectionDropDownValueChanged(app, event)
            app.algo = app.AlgorithmSelectionDropDown.Value;
            disp(app.algo)
            if app.algo == "Column Modulation"
                app.algoSliderText.Text = 'Col Width';
                app.algoSlider.Limits = [5, 15];
                app.algoSlider.Value = 10;
            elseif app.algo == "Canny Edges"
                app.algoSliderText.Text = 'Threshold';
                app.algoSlider.Limits = [.2, .9];
                app.algoSlider.Value = .6;                
            elseif app.algo == "Contours"
                app.algoSliderText.Text = 'Cutoff';
                app.algoSlider.Limits = [0, 255];
                app.algoSlider.Value = 150;                
            end
        end

        % Button pushed function: Run_button
        function Run_buttonButtonPushed(app, event)
            app.run();
        end

        % Value changed function: algoSlider
        function algoSliderValueChanged(app, event)
            app.algoSliderVal = app.algoSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.figure = uiaxes(app.UIFigure);
            app.figure.XTick = [];
            app.figure.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.MajorTicks = [];
            app.Knob.MinorTicks = [];
            app.Knob_2 = uiknob(app.Panel, 'continuous');
            app.Knob_2.MajorTicks = [];
            app.Knob_2.MinorTicks = [];
            app.ROBOTICETCHASKETCHLabel = uilabel(app.UIFigure);
            app.ROBOTICETCHASKETCHLabel.Text = 'ROBOTIC ETCH-A-SKETCH';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.ResolutionSliderLabel = uilabel(app.UIFigure);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.AddTextToImageLabel_2 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_2.Text = 'File Name:';
            app.FileName_text = uieditfield(app.UIFigure, 'text');
            app.FileName_text.Editable = 'off';
            app.FileName_text.Value = 'image.jpg';
            app.OpenImage_button = uibutton(app.UIFigure, 'push');
            app.OpenImage_button.ButtonPushedFcn = createCallbackFcn(app, @OpenImage_buttonButtonPushed, true);
            app.OpenImage_button.Text = 'Open Image';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-5 5];
            app.ContrastSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.ContrastSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-5 5];
            app.BrightnessSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.BrightnessSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.ResolutionSlider = uislider(app.UIFigure);
            app.ResolutionSlider.Limits = [-5 5];
            app.ResolutionSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.ResolutionSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.AddTextToImageLabel = uilabel(app.UIFigure);
            app.AddTextToImageLabel.Text = 'Add Text To Image: ';
            app.EnterText = uieditfield(app.UIFigure, 'text');
            app.EnterText.ValueChangedFcn = createCallbackFcn(app, @EnterTextValueChanged, true);
            app.EnterText.Value = 'Sample Text';
            app.Preview_button = uibutton(app.UIFigure, 'push');
            app.Preview_button.ButtonPushedFcn = createCallbackFcn(app, @Preview_buttonButtonPushed, true);
            app.Preview_button.Text = 'Preview Path';
            app.AddTextToImageLabel_4 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_4.Text = 'Text Location (x, y):';
            app.TextLocationX_button = uieditfield(app.UIFigure, 'text');
            app.TextLocationX_button.ValueChangedFcn = createCallbackFcn(app, @TextLocationX_buttonValueChanged, true);
            app.TextLocationX_button.Value = '100';
            app.Reset_button = uibutton(app.UIFigure, 'push');
            app.Reset_button.ButtonPushedFcn = createCallbackFcn(app, @Reset_buttonButtonPushed, true);
            app.Reset_button.Text = 'Reset';
            app.AddTextToImageLabel_3 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_3.Text = 'Estimated Time To Print: ';
            app.EstimatedTime_text = uieditfield(app.UIFigure, 'text');
            app.EstimatedTime_text.Editable = 'off';
            app.EstimatedTime_text.Value = 'XX:XX';
            app.Run_button = uibutton(app.UIFigure, 'push');
            app.Run_button.ButtonPushedFcn = createCallbackFcn(app, @Run_buttonButtonPushed, true);
            app.Run_button.Text = 'Run!';
            app.TextLocationY_button = uieditfield(app.UIFigure, 'text');
            app.TextLocationY_button.ValueChangedFcn = createCallbackFcn(app, @TextLocationY_buttonValueChanged, true);
            app.TextLocationY_button.Value = '300';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EaS_img.jpg');
            app.AlgorithmSelectionDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmSelectionDropDownLabel.Text = 'Algorithm Selection';
            app.AlgorithmSelectionDropDown = uidropdown(app.UIFigure);
            app.AlgorithmSelectionDropDown.Items = {'Canny Edges', 'Contours', 'Column Modulation'};
            app.AlgorithmSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmSelectionDropDownValueChanged, true);
            app.AlgorithmSelectionDropDown.Value = 'Contours';
            app.algoSlider = uislider(app.UIFigure);
            app.algoSlider.Limits = [0 255];
            app.algoSlider.MajorTicks = [];
            app.algoSlider.MajorTickLabels = {''};
            app.algoSlider.ValueChangedFcn = createCallbackFcn(app, @algoSliderValueChanged, true);
            app.algoSlider.MinorTicks = [];
            app.algoSlider.Value = 150;
            app.algoSliderText = uilabel(app.UIFigure);
            app.algoSliderText.Text = 'Cutoff';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etchAsketchapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end